classdef adapt_aep < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Label_time_left                matlab.ui.control.Label
        EstimatedtimeleftLabel         matlab.ui.control.Label
        DropDown_trial_mode            matlab.ui.control.DropDown
        DropDown_test_tag              matlab.ui.control.DropDown
        EditField_timer                matlab.ui.control.NumericEditField
        TimerdurationLabel             matlab.ui.control.Label
        DropDown_test_mode             matlab.ui.control.DropDown
        DropDown_response_feature      matlab.ui.control.DropDown
        Label_signal_snr               matlab.ui.control.Label
        SignalSNRLabel                 matlab.ui.control.Label
        dBSPLLabel                     matlab.ui.control.Label
        Label_tank_noise_floor         matlab.ui.control.Label
        TanknoisefloorLabel            matlab.ui.control.Label
        TempButton                     matlab.ui.control.Button
        HealthButton                   matlab.ui.control.Button
        DropDown_sex                   matlab.ui.control.DropDown
        PauseButton                    matlab.ui.control.Button
        Label_time_elapsed             matlab.ui.control.Label
        TotaltimeelapsedLabel          matlab.ui.control.Label
        peramplitudeLabel              matlab.ui.control.Label
        DropDown_tone_burst            matlab.ui.control.DropDown
        StatusLabel                    matlab.ui.control.Label
        InitializeButton               matlab.ui.control.Button
        AnalysisLabel                  matlab.ui.control.Label
        MonitorLabel                   matlab.ui.control.Label
        StartButton                    matlab.ui.control.Button
        CalibrateButton                matlab.ui.control.Button
        logo                           matlab.ui.control.Image
        EditField_max_trials           matlab.ui.control.NumericEditField
        EditField_frequency            matlab.ui.control.NumericEditField
        EditField_ID                   matlab.ui.control.NumericEditField
        ExperimentLabel                matlab.ui.control.Label
        MaximumtrialsLabel_2           matlab.ui.control.Label
        HzLabel                        matlab.ui.control.Label
        StimulusLabel                  matlab.ui.control.Label
        SexLabel                       matlab.ui.control.Label
        IDLabel                        matlab.ui.control.Label
        TestSubjectLabel               matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        minutesLabel                   matlab.ui.control.Label
        SelectTestModetopopulateLabel  matlab.ui.control.Label
        UIAxes_accel3d                 matlab.ui.control.UIAxes
        UIAxes_funfetti                matlab.ui.control.UIAxes
        UIAxes_live_fft                matlab.ui.control.UIAxes
        UIAxes_gate                    matlab.ui.control.UIAxes
        UIAxes_tank_noise_floor        matlab.ui.control.UIAxes
        UIAxes_signal_SNR              matlab.ui.control.UIAxes
        UIAxes_slope_est               matlab.ui.control.UIAxes
        UIAxes_thresh_est              matlab.ui.control.UIAxes
        UIAxes_diff_fft                matlab.ui.control.UIAxes
        UIAxes_health                  matlab.ui.control.UIAxes
        UIAxes_model                   matlab.ui.control.UIAxes
        UIAxes_boot                    matlab.ui.control.UIAxes
        UIAxes_ch4                     matlab.ui.control.UIAxes
        UIAxes_ch3                     matlab.ui.control.UIAxes
        UIAxes_ch2                     matlab.ui.control.UIAxes
        UIAxes_ch1                     matlab.ui.control.UIAxes
        UIAxes_hydrophone              matlab.ui.control.UIAxes
    end


    properties (Access = public)
        ex % Experiment structure
        PauseFlag logical = false
    end

    methods (Access = private)
        function clearAllAxes(app)
            cla(app.UIAxes_hydrophone);
            title(app.UIAxes_hydrophone,'Hydrophone')
            cla(app.UIAxes_ch1);
            cla(app.UIAxes_ch2);
            cla(app.UIAxes_ch3);
            cla(app.UIAxes_ch4);
            cla(app.UIAxes_boot);
            cla(app.UIAxes_model);
            title(app.UIAxes_model,'Model')
            cla(app.UIAxes_diff_fft);
            cla(app.UIAxes_thresh_est);
            cla(app.UIAxes_slope_est);
            cla(app.UIAxes_gate);
            cla(app.UIAxes_tank_noise_floor);
            cla(app.UIAxes_signal_SNR);

            % --- funfetti: also undo the Mixed-mode heatmap (imagesc) state ---
            cla(app.UIAxes_funfetti);
            delete(allchild(app.UIAxes_funfetti));
            delete(app.UIAxes_funfetti.Legend);              % errorbar legend from Timed/Static
            app.UIAxes_funfetti.YDir           = 'normal';   % imagesc set this to 'reverse'
            app.UIAxes_funfetti.XLimMode       = 'auto';
            app.UIAxes_funfetti.YLimMode       = 'auto';
            app.UIAxes_funfetti.CLimMode       = 'auto';
            app.UIAxes_funfetti.XTickMode      = 'auto';
            app.UIAxes_funfetti.YTickMode      = 'auto';
            app.UIAxes_funfetti.XTickLabelMode = 'auto';
            app.UIAxes_funfetti.YTickLabelMode = 'auto';
            colormap(app.UIAxes_funfetti, 'parula');         % drop the custom blue heatmap colormap
            title(app.UIAxes_funfetti, '2f Response Tracking')
            xlabel(app.UIAxes_funfetti, 'Iteration')
            ylabel(app.UIAxes_funfetti, '2f Magnitude (\muV)')
            app.UIAxes_funfetti.Box = 'on';
            app.UIAxes_funfetti.Visible = 'off';

            cla(app.UIAxes_live_fft);
            delete(allchild(app.UIAxes_live_fft));
            delete(app.UIAxes_live_fft.Legend);
            title(app.UIAxes_live_fft, 'Live FFT Monitor')
            xlabel(app.UIAxes_live_fft, 'Frequency (Hz)')
            ylabel(app.UIAxes_live_fft, 'Magnitude (\muV)')
            app.UIAxes_live_fft.Box = 'on';
            app.UIAxes_live_fft.Visible = 'off';

            % Hodograph
            cla(app.UIAxes_accel3d);
            app.UIAxes_accel3d.Visible = 'off';
        end

        function setAxesVisibility(app)
            mode = app.DropDown_test_mode.Value;
            on   = ismember(mode, {'Timed','Mixed freqs'});
            isAccel = strcmp(app.DropDown_test_tag.Value, 'accelerometer');
            app.UIAxes_funfetti.Visible   = on;
            app.UIAxes_live_fft.Visible   = on;
            app.UIAxes_accel3d.Visible    = on && isAccel;
            if isAccel
                app.UIAxes_funfetti.InnerPosition = [800 60 300 330];   % half width
            else
                app.UIAxes_funfetti.InnerPosition = [800 60 670 330];   % normal
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath(fileparts(mfilename('fullpath'))))
            app.UIAxes_live_fft.PositionConstraint = 'innerposition';
            app.UIAxes_funfetti.PositionConstraint = 'innerposition';
            app.UIAxes_live_fft.InnerPosition = [800 470 670 240];
            app.UIAxes_funfetti.InnerPosition = [800 60 670 330];

            % Hodograph
            app.UIAxes_accel3d.Visible = 'off';
            app.UIAxes_accel3d.PositionConstraint = 'innerposition';
            app.UIAxes_accel3d.InnerPosition = [1170 60 300 330];
        end

        % Button pushed function: StartButton
        function start_exp(app, event)
             fprintf('\nStarting experiment...\n')

            % Clear GUI
            clearAllAxes(app)
            setAxesVisibility(app)
            app.Label_time_elapsed.Text = 'X';
            app.Label_tank_noise_floor.Text = 'X';
            app.Label_signal_snr.Text = 'X';
            app.Label_time_left.Text = 'X';

            app.CalibrateButton.Enable = 'off';
            app.InitializeButton.Enable = 'off';
            app.TempButton.Enable = 'off';
            app.HealthButton.Enable = 'off';
            app.PauseButton.Enable = 'off';

            % Setup accelerometer figure if needed
            if strcmp(app.DropDown_test_tag.Value, 'accelerometer')
                xlabel(app.UIAxes_funfetti, 'Dimension')
                ylabel(app.UIAxes_funfetti, 'Acceleration (dB re: 1\mum/s^2)')
                title(app.UIAxes_funfetti, 'Accelerometer')
            end

            % Rip it
            if strcmp(app.DropDown_test_mode.Value,'Timed')
                app.ex = run_single(app);
                fprintf('\nFinished testing at current frequency.')
            elseif strcmp(app.DropDown_test_mode.Value,'Mixed freqs')
                app.ex = run_mixed(app);
                fprintf('\nFinished testing stimulus schedule.')
            end
            
            % Finished sound effect!
            uialert(app.UIFigure, 'For 2 minutes, do not interact with MATLAB to prevent save file corruption', 'Prevent file corruption')
        
            % Reset all controls to initial state
            app.EditField_ID.Enable = 'on';
            app.EditField_frequency.Enable = 'on';
            app.EditField_frequency.Value = [];
            app.EditField_max_trials.Enable = 'on';
            app.DropDown_response_feature.Enable = 'on';
            app.DropDown_response_feature.Value = 'Double frequency';
            app.DropDown_test_mode.Enable = 'on';
            app.DropDown_test_mode.Value = 'Select test mode';
            app.DropDown_test_tag.Enable = 'off';
            app.DropDown_test_tag.Value = 'Select test tag';
            app.DropDown_sex.Enable = 'on';
            app.DropDown_tone_burst.Enable = 'on';
            app.DropDown_tone_burst.Value = 'Tone burst';
            app.DropDown_trial_mode.Enable = 'off';
            app.DropDown_trial_mode.Value  = 'Select trial mode';

            app.InitializeButton.Enable = 'on';
            app.CalibrateButton.Enable = 'off';
            app.HealthButton.Enable = 'off';
            app.TempButton.Enable = 'off';
            app.StartButton.Enable = 'off';
            app.PauseButton.Enable = 'off';

            app.Label_time_elapsed.Text = 'X';
            app.Label_tank_noise_floor.Text = 'X';
            app.Label_signal_snr.Text = 'X';
            app.Label_time_left.Text = 'X';
            app.EditField_max_trials.Visible = "off";
            app.EditField_timer.Visible        = 'off';
            app.TimerdurationLabel.Visible     = 'off';
            app.minutesLabel.Visible           = 'off';
            app.MaximumtrialsLabel_2.Visible   = 'off';
            app.peramplitudeLabel.Visible      = 'off';

            app.SelectTestModetopopulateLabel.Visible = "on";

            app.ex = [];
            app.PauseFlag = false;
            clearAllAxes(app);
            setAxesVisibility(app)

        end

        % Button pushed function: CalibrateButton
        function start_calibration(app, event)
            fprintf('\nStarting calibration...\n')

            % Check if ex has been initialized
            if isempty(app.ex) || ~isfield(app.ex, 'info')
                uialert(app.UIFigure, 'Please initialize experiment first.', 'Not Initialized');
                return;
            end

            % Create the calibration app
            calibration_app = calibrate_stimulus();

            % Initialize it with ex
            calibration_app.initializeWithEx(app.ex);

            % Automatic button push
            t = timer('StartDelay', 0.1, 'TimerFcn', @(varargin) calibration_app.runCalibrate());
            start(t);
            uiwait(calibration_app.UIFigure);  % now listening before button fires
            delete(t);

            % Get the updated ex back
            app.ex = calibration_app.ex;

            % Clean up
            delete(calibration_app)

            app.CalibrateButton.Enable = 'on';
            % Only turn on health button if we are not testing with the
            % accelerometer
            app.HealthButton.Enable = ~strcmp(app.DropDown_test_tag.Value, 'accelerometer');
            app.StartButton.Enable = "on";

            fprintf('\nFinished calibration\n')
        end

        % Button pushed function: InitializeButton
        function start_init(app, event)
            fprintf('\nStarting initalization\n')

            % Initialize ex structure
            app.ex = setup_ex(app);

            % Check if all required fields are filled
            if isempty(app.EditField_frequency.Value) || isempty(app.EditField_ID.Value) || strcmp(app.DropDown_sex.Value,'X') || ...
                    isnan(app.EditField_frequency.Value) || strcmp(app.DropDown_response_feature.Value, 'Select response feature') || ...
                    isnan(app.EditField_max_trials.Value) || strcmp(app.DropDown_test_mode.Value,'Select test mode') || ...
                    strcmp(app.DropDown_test_tag.Value, 'Select test tag') ||strcmp(app.DropDown_trial_mode.Value, 'Select trial mode')

                uialert(app.UIFigure, 'Make sure all fields are filled out prior to continuing.', 'Missing Fields');
                return;
            end

            % Make sure accelerometer only uses the Mixed freqs mode
            if strcmp(app.DropDown_test_tag.Value, 'accelerometer') && ~strcmp(app.DropDown_test_mode.Value, 'Mixed freqs')
                uialert(app.UIFigure, 'Accelerometer tests require Mixed freqs test mode.', 'Invalid Test Mode');
                return;
            end

            % Setup info
            app.ex = setup_info(app.ex,app); % User input of experiment parameters
            
            % Extract GUI Values
            app.ex.info.animal.subject_ID = app.EditField_ID.Value;
            app.ex.info.animal.sex = app.DropDown_sex.Value;
            app.ex.info.experiment.response_feature = app.DropDown_response_feature.Value;
            app.ex.info.trials.max_trials = app.EditField_max_trials.Value;
            app.ex.info.experiment.timer_dur_min = app.EditField_timer.Value;
            app.ex.info.experiment.exp_type = app.DropDown_test_mode.Value;
            app.ex.info.experiment.test_tag = app.DropDown_test_tag.Value;

            % Setup stimulus info
            app.ex = setup_stimulus_info(app.ex,app);
            
            % Create filename
            if strcmp(app.DropDown_test_mode.Value,'Mixed freqs')
                freq_vec = app.ex.info.mixed.stim_freqs;
                freq_str = strjoin(string(freq_vec), '_');   % e.g. "100-200-400"
                fname = sprintf('%s', freq_str);
            else
                fname = string(app.ex.info.stimulus(1).frequency_hz);
            end
            app.ex.info.animal.filename_root = sprintf('%s_%d_%sHz', ...
                app.ex.info.animal.species_name, ...
                app.ex.info.animal.subject_ID, ...
                fname);

            % Set up sets of ex
            app.ex = setup_health(app.ex);
            app.ex = setup_block(app.ex); % Per amplitude meta/data
            app.ex = setup_hardware(app.ex);
            if strcmp(app.DropDown_test_mode.Value,'Mixed freqs')
                app.ex = setup_analysis(app.ex); % Analysis deta/data
            end

            %% Generate Stimulus Template
            app.ex = plan_stimulus_template(app.ex);

            % GUI Buttons
            app.InitializeButton.Enable = 'off';
            app.EditField_ID.Enable = 'off';
            app.EditField_frequency.Enable = 'off';
            app.EditField_max_trials.Enable = 'off';
            app.DropDown_response_feature.Enable = 'off';
            app.DropDown_test_mode.Enable = 'off';
            app.DropDown_test_tag.Enable = 'off';
            app.DropDown_sex.Enable = 'on';
            app.DropDown_sex.Enable = "off";
            app.DropDown_tone_burst.Enable = "off";
            app.DropDown_test_mode.Enable = "off";
            app.DropDown_trial_mode.Enable = 'off';

            app.EditField_max_trials.Enable   = 'off';
            app.EditField_max_trials.Editable = 'off';

            app.EditField_timer.Editable = 'off';
            app.EditField_timer.Enable   = 'off';

            % Don't require health check if we are using accelerometer
            isAccel = strcmp(app.DropDown_test_tag.Value, 'accelerometer');
            app.HealthButton.Enable    = ~isAccel;
            app.CalibrateButton.Enable = isAccel;

            fprintf('\nInitialization complete\n')

        end

        % Button pushed function: HealthButton
        function HealthButtonPushed(app, event)
             fprintf('\nStarting health check...\n')

            % app.HealthButton.Enable = 'off';
            app.ex = check_health(app.ex,app, 1);

            app.HealthButton.Enable = 'on';
            app.TempButton.Enable = 'off';
            app.CalibrateButton.Enable = 'on';

            fprintf('\nFinished health check\n')
        end

        % Button pushed function: TempButton
        function TempButtonPushed(app, event)
            fprintf('\nStarting temperature check...\n')
            app.TempButton.Enable = 'off';
            app.StartButton.Enable = 'on';
            fprintf('\nFinished temperature check\n')
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            
        end

        % Value changed function: DropDown_test_mode
        function DropDown_test_modeValueChanged(app, event)
            value = app.DropDown_test_mode.Value;
            app.SelectTestModetopopulateLabel.Visible = "off";

            isTimed    = strcmp(value, 'Timed');
            isMixed    = strcmp(value, 'Mixed freqs');

            % Timer controls
            app.EditField_timer.Visible    = isTimed;
            app.TimerdurationLabel.Visible = isTimed;
            app.minutesLabel.Visible       = isTimed;

            % Defaults per mode
            if isTimed
                app.EditField_timer.Editable   = 'on';
                app.EditField_timer.Enable     = 'on';
                app.EditField_frequency.Visible = 'on';
                app.EditField_frequency.Value = 100;
                app.HzLabel.Visible = 'on';
                app.EditField_timer.Value      = 15;
                app.EditField_max_trials.Value = 200;
                app.DropDown_tone_burst.Visible = 'on';
            elseif isMixed
                app.EditField_frequency.Visible = 'off';
                app.HzLabel.Visible = 'off';
                app.EditField_frequency.Value = 0;
                app.DropDown_tone_burst.Visible = 'on';
                app.EditField_timer.Value      = 0;
                app.EditField_timer.Visible      = 'off';
                app.EditField_max_trials.Value = 0;
            end
            app.DropDown_trial_mode.Enable = ~strcmp(value,'Select test mode') && ~isTimed;
            app.DropDown_test_tag.Enable = 'on';
            if isTimed
                app.DropDown_trial_mode.Value = 'Static';
            elseif isMixed
                app.DropDown_test_tag.Value   = 'mixed_freqs';
            end


            % Axes visibility
            showLivePlots = isTimed || isMixed;
            app.UIAxes_funfetti.Visible   = showLivePlots;
            app.UIAxes_live_fft.Visible   = showLivePlots;
        end

        % Value changed function: DropDown_test_tag
        function DropDown_test_tagValueChanged(app, event)
            setAxesVisibility(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_hydrophone = uiaxes(app.UIFigure);
            title(app.UIAxes_hydrophone, 'Hydrophone')
            ylabel(app.UIAxes_hydrophone, 'Amplitude (mV)')
            zlabel(app.UIAxes_hydrophone, 'Z')
            app.UIAxes_ch1 = uiaxes(app.UIFigure);
            title(app.UIAxes_ch1, 'Channel 1')
            zlabel(app.UIAxes_ch1, 'Z')
            app.UIAxes_ch2 = uiaxes(app.UIFigure);
            title(app.UIAxes_ch2, 'Channel 2')
            ylabel(app.UIAxes_ch2, 'Amplitude (\muV)')
            zlabel(app.UIAxes_ch2, 'Z')
            app.UIAxes_ch3 = uiaxes(app.UIFigure);
            title(app.UIAxes_ch3, 'Channel 3')
            zlabel(app.UIAxes_ch3, 'Z')
            app.UIAxes_ch4 = uiaxes(app.UIFigure);
            title(app.UIAxes_ch4, 'Channel 4')
            xlabel(app.UIAxes_ch4, 'Time (s)')
            zlabel(app.UIAxes_ch4, 'Z')
            app.UIAxes_boot = uiaxes(app.UIFigure);
            title(app.UIAxes_boot, 'Bootstrap Results')
            ylabel(app.UIAxes_boot, 'Lower CI Value')
            zlabel(app.UIAxes_boot, 'Z')
            app.UIAxes_boot.Visible = 'off';
            app.UIAxes_model = uiaxes(app.UIFigure);
            title(app.UIAxes_model, 'Model')
            xlabel(app.UIAxes_model, 'Stimulus Amplitude (dB SPL)')
            ylabel(app.UIAxes_model, '2f Amplitude (\muV)')
            zlabel(app.UIAxes_model, 'Z')
            app.UIAxes_model.Visible = 'off';
            app.UIAxes_health = uiaxes(app.UIFigure);
            title(app.UIAxes_health, 'EKG')
            xlabel(app.UIAxes_health, 'Time (s)')
            ylabel(app.UIAxes_health, 'Amplitude (\muV)')
            app.UIAxes_diff_fft = uiaxes(app.UIFigure);
            title(app.UIAxes_diff_fft, 'Difference FFT')
            xlabel(app.UIAxes_diff_fft, 'Frequency (Hz)')
            ylabel(app.UIAxes_diff_fft, 'Amplitude (\muV)')
            zlabel(app.UIAxes_diff_fft, 'Z')
            app.UIAxes_diff_fft.Visible = 'off';
            app.UIAxes_thresh_est = uiaxes(app.UIFigure);
            title(app.UIAxes_thresh_est, 'Threshold Estimate')
            xlabel(app.UIAxes_thresh_est, 'Iteration')
            ylabel(app.UIAxes_thresh_est, 'x0')
            zlabel(app.UIAxes_thresh_est, 'Z')
            app.UIAxes_thresh_est.Visible = 'off';
            app.UIAxes_slope_est = uiaxes(app.UIFigure);
            title(app.UIAxes_slope_est, 'Slope Estimate')
            xlabel(app.UIAxes_slope_est, 'Iteration')
            ylabel(app.UIAxes_slope_est, 'm')
            zlabel(app.UIAxes_slope_est, 'Z')
            app.UIAxes_slope_est.Visible = 'off';
            app.UIAxes_signal_SNR = uiaxes(app.UIFigure);
            title(app.UIAxes_signal_SNR, 'Signal SNR')
            ylabel(app.UIAxes_signal_SNR, 'SNR (dB)')
            zlabel(app.UIAxes_signal_SNR, 'Z')
            app.UIAxes_tank_noise_floor = uiaxes(app.UIFigure);
            title(app.UIAxes_tank_noise_floor, 'Tank Noise Floor ')
            ylabel(app.UIAxes_tank_noise_floor, 'dB SPL')
            zlabel(app.UIAxes_tank_noise_floor, 'Z')
            app.UIAxes_gate = uiaxes(app.UIFigure);
            title(app.UIAxes_gate, 'Detection Gate Type ')
            xlabel(app.UIAxes_gate, 'Gate type')
            ylabel(app.UIAxes_gate, 'Frequency')
            zlabel(app.UIAxes_gate, 'Z')
            app.UIAxes_gate.Visible = 'off';
            app.UIAxes_live_fft = uiaxes(app.UIFigure);
            title(app.UIAxes_live_fft, ' ')
            zlabel(app.UIAxes_live_fft, 'Z')
            app.UIAxes_live_fft.Visible = 'off';
            app.UIAxes_funfetti = uiaxes(app.UIFigure);
            title(app.UIAxes_funfetti, ' ')
            xlabel(app.UIAxes_funfetti, ' ')
            ylabel(app.UIAxes_funfetti, ' ')
            zlabel(app.UIAxes_funfetti, ' ')
            app.UIAxes_funfetti.Visible = 'off';
            app.UIAxes_accel3d = uiaxes(app.UIFigure);
            title(app.UIAxes_accel3d, ' ')
            xlabel(app.UIAxes_accel3d, ' ')
            ylabel(app.UIAxes_accel3d, ' ')
            zlabel(app.UIAxes_accel3d, 'Z')
            app.SelectTestModetopopulateLabel = uilabel(app.UIFigure);
            app.SelectTestModetopopulateLabel.Text = 'Select ''Test Mode'' to populate';
            app.minutesLabel = uilabel(app.UIFigure);
            app.minutesLabel.Visible = 'off';
            app.minutesLabel.Text = 'minute(s)';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '__________________________';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '__________________________';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '__________________________';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '__________________________';
            app.TestSubjectLabel = uilabel(app.UIFigure);
            app.TestSubjectLabel.Text = 'Test / Subject';
            app.IDLabel = uilabel(app.UIFigure);
            app.IDLabel.Text = 'ID #:';
            app.SexLabel = uilabel(app.UIFigure);
            app.SexLabel.Text = 'Sex:';
            app.StimulusLabel = uilabel(app.UIFigure);
            app.StimulusLabel.Text = 'Stimulus';
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Visible = 'off';
            app.HzLabel.Text = 'Hz';
            app.MaximumtrialsLabel_2 = uilabel(app.UIFigure);
            app.MaximumtrialsLabel_2.Visible = 'off';
            app.MaximumtrialsLabel_2.Text = 'Maximum trials:';
            app.ExperimentLabel = uilabel(app.UIFigure);
            app.ExperimentLabel.Text = 'Experiment';
            app.EditField_ID = uieditfield(app.UIFigure, 'numeric');
            app.EditField_ID.AllowEmpty = 'on';
            app.EditField_ID.Value = [];
            app.EditField_frequency = uieditfield(app.UIFigure, 'numeric');
            app.EditField_frequency.AllowEmpty = 'on';
            app.EditField_frequency.Visible = 'off';
            app.EditField_frequency.Value = [];
            app.EditField_max_trials = uieditfield(app.UIFigure, 'numeric');
            app.EditField_max_trials.Editable = 'off';
            app.EditField_max_trials.Enable = 'off';
            app.EditField_max_trials.Visible = 'off';
            app.logo = uiimage(app.UIFigure);
            app.logo.ImageSource = fullfile(pathToMLAPP, 'adapt_aep_logo.png');
            app.CalibrateButton = uibutton(app.UIFigure, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @start_calibration, true);
            app.CalibrateButton.Enable = 'off';
            app.CalibrateButton.Text = 'Calibrate';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @start_exp, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.MonitorLabel = uilabel(app.UIFigure);
            app.MonitorLabel.Text = 'Monitor';
            app.AnalysisLabel = uilabel(app.UIFigure);
            app.AnalysisLabel.Text = 'Analysis';
            app.InitializeButton = uibutton(app.UIFigure, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @start_init, true);
            app.InitializeButton.Text = 'Initialize';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status';
            app.DropDown_tone_burst = uidropdown(app.UIFigure);
            app.DropDown_tone_burst.Items = {'Tone burst'};
            app.DropDown_tone_burst.Visible = 'off';
            app.DropDown_tone_burst.Value = 'Tone burst';
            app.peramplitudeLabel = uilabel(app.UIFigure);
            app.peramplitudeLabel.Visible = 'off';
            app.peramplitudeLabel.Text = 'per amplitude';
            app.TotaltimeelapsedLabel = uilabel(app.UIFigure);
            app.TotaltimeelapsedLabel.Text = 'Total time elapsed:';
            app.Label_time_elapsed = uilabel(app.UIFigure);
            app.Label_time_elapsed.Text = 'X';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Enable = 'off';
            app.PauseButton.Text = 'Pause';
            app.DropDown_sex = uidropdown(app.UIFigure);
            app.DropDown_sex.Items = {'X', 'F', 'M', 'Test'};
            app.DropDown_sex.Value = 'X';
            app.HealthButton = uibutton(app.UIFigure, 'push');
            app.HealthButton.ButtonPushedFcn = createCallbackFcn(app, @HealthButtonPushed, true);
            app.HealthButton.Enable = 'off';
            app.HealthButton.Text = 'Health';
            app.TempButton = uibutton(app.UIFigure, 'push');
            app.TempButton.ButtonPushedFcn = createCallbackFcn(app, @TempButtonPushed, true);
            app.TempButton.Enable = 'off';
            app.TempButton.Text = 'Temp';
            app.TanknoisefloorLabel = uilabel(app.UIFigure);
            app.TanknoisefloorLabel.Text = 'Tank noise floor:';
            app.Label_tank_noise_floor = uilabel(app.UIFigure);
            app.Label_tank_noise_floor.Text = 'X';
            app.dBSPLLabel = uilabel(app.UIFigure);
            app.dBSPLLabel.Text = 'dB SPL';
            app.SignalSNRLabel = uilabel(app.UIFigure);
            app.SignalSNRLabel.Text = 'Signal SNR:';
            app.Label_signal_snr = uilabel(app.UIFigure);
            app.Label_signal_snr.Text = 'X';
            app.DropDown_response_feature = uidropdown(app.UIFigure);
            app.DropDown_response_feature.Items = {'Double frequency', 'Select response feature'};
            app.DropDown_response_feature.Value = 'Double frequency';
            app.DropDown_test_mode = uidropdown(app.UIFigure);
            app.DropDown_test_mode.Items = {'Select test mode', 'Timed', 'Mixed freqs'};
            app.DropDown_test_mode.ValueChangedFcn = createCallbackFcn(app, @DropDown_test_modeValueChanged, true);
            app.DropDown_test_mode.Value = 'Select test mode';
            app.TimerdurationLabel = uilabel(app.UIFigure);
            app.TimerdurationLabel.Visible = 'off';
            app.TimerdurationLabel.Text = 'Timer duration:';
            app.EditField_timer = uieditfield(app.UIFigure, 'numeric');
            app.EditField_timer.Editable = 'off';
            app.EditField_timer.Enable = 'off';
            app.EditField_timer.Visible = 'off';
            app.DropDown_test_tag = uidropdown(app.UIFigure);
            app.DropDown_test_tag.Items = {'Select test tag', 'test', 'benzo', 'mixed_freqs', 'accelerometer'};
            app.DropDown_test_tag.ValueChangedFcn = createCallbackFcn(app, @DropDown_test_tagValueChanged, true);
            app.DropDown_test_tag.Enable = 'off';
            app.DropDown_test_tag.Value = 'Select test tag';
            app.DropDown_trial_mode = uidropdown(app.UIFigure);
            app.DropDown_trial_mode.Items = {'Select trial mode', 'Static'};
            app.DropDown_trial_mode.Enable = 'off';
            app.DropDown_trial_mode.Value = 'Select trial mode';
            app.EstimatedtimeleftLabel = uilabel(app.UIFigure);
            app.EstimatedtimeleftLabel.Text = 'Estimated time left: ';
            app.Label_time_left = uilabel(app.UIFigure);
            app.Label_time_left.Text = 'X';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = adapt_aep

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end