classdef calibrate_stimulus < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        button_calibrate          matlab.ui.control.Button
        label_corr_level          matlab.ui.control.Label
        label_corr_factor         matlab.ui.control.Label
        label_uncorr_level        matlab.ui.control.Label
        label_target_amp          matlab.ui.control.Label
        label_stim_amp            matlab.ui.control.Label
        label_stim_freq           matlab.ui.control.Label
        CorrectedleveldBLabel     matlab.ui.control.Label
        CorrectionfactordBLabel   matlab.ui.control.Label
        UncorrectedLeveldBLabel   matlab.ui.control.Label
        TargetdBSPLLabel          matlab.ui.control.Label
        AmplitudedBLabel          matlab.ui.control.Label
        FrequencyHzLabel          matlab.ui.control.Label
        StimulusCalibrationLabel  matlab.ui.control.Label
        ax_calibration_levels_2   matlab.ui.control.UIAxes
        ax_calibration_levels     matlab.ui.control.UIAxes
        ax_hydrophone             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ex % Experiment structure
    end
    
    methods (Access = public)
        % Constructor: Receives ex from run_adapt_aep.mlapp
        function app = calibrate_stimulus(ex)
            createComponents(app)
            app.ex = ex;
            registerApp(app, app.UIFigure)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: button_calibrate
        function button_calibrateButtonPushed(app, event)
            app.button_calibrate.Enable = "off";
            try
                app.ex = run_calibrate_stimulus(app,app.ex);
                if ~app.ex.calibration.check_pass
                    app.button_calibrate.Enable = "on";
                    app.button_calibrate.Text = "Calibrate again?";
                    app.button_calibrate.BackgroundColor = [1 0 0];
                else
                    uiresume(app.UIFigure)
                end
            catch
                warning('Error running run_calibration')
                app.button_calibrate.Enable = "on";
            end
        end

        % Callback function: not associated with a component
        function button_checkButtonPushed(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ax_hydrophone = uiaxes(app.UIFigure);
            title(app.ax_hydrophone, 'Hydrophone Signal')
            xlabel(app.ax_hydrophone, 'Time')
            ylabel(app.ax_hydrophone, 'Amplitude (μV)')
            zlabel(app.ax_hydrophone, 'Z')
            app.ax_calibration_levels = uiaxes(app.UIFigure);
            title(app.ax_calibration_levels, 'Calibration Levels')
            xlabel(app.ax_calibration_levels, 'Frequency')
            ylabel(app.ax_calibration_levels, 'Amplitude (dB)')
            zlabel(app.ax_calibration_levels, 'Z')
            app.ax_calibration_levels_2 = uiaxes(app.UIFigure);
            title(app.ax_calibration_levels_2, 'Hydrophone Spectra')
            xlabel(app.ax_calibration_levels_2, 'Frequency')
            ylabel(app.ax_calibration_levels_2, 'Amplitude (dB)')
            zlabel(app.ax_calibration_levels_2, 'Z')
            app.StimulusCalibrationLabel = uilabel(app.UIFigure);
            app.StimulusCalibrationLabel.Text = 'Stimulus Calibration';
            app.FrequencyHzLabel = uilabel(app.UIFigure);
            app.FrequencyHzLabel.Text = 'Frequency (Hz):';
            app.AmplitudedBLabel = uilabel(app.UIFigure);
            app.AmplitudedBLabel.Text = 'Amplitude (dB):';
            app.TargetdBSPLLabel = uilabel(app.UIFigure);
            app.TargetdBSPLLabel.Text = 'Target dB SPL:';
            app.UncorrectedLeveldBLabel = uilabel(app.UIFigure);
            app.UncorrectedLeveldBLabel.Text = 'Uncorrected Level (dB):';
            app.CorrectionfactordBLabel = uilabel(app.UIFigure);
            app.CorrectionfactordBLabel.Text = 'Correction factor (dB):';
            app.CorrectedleveldBLabel = uilabel(app.UIFigure);
            app.CorrectedleveldBLabel.Text = 'Corrected level (dB):';
            app.label_stim_freq = uilabel(app.UIFigure);
            app.label_stim_freq.Text = 'X';
            app.label_stim_amp = uilabel(app.UIFigure);
            app.label_stim_amp.Text = 'X';
            app.label_target_amp = uilabel(app.UIFigure);
            app.label_target_amp.Text = 'X';
            app.label_uncorr_level = uilabel(app.UIFigure);
            app.label_uncorr_level.Text = 'X';
            app.label_corr_factor = uilabel(app.UIFigure);
            app.label_corr_factor.Text = 'X';
            app.label_corr_level = uilabel(app.UIFigure);
            app.label_corr_level.Text = 'X';
            app.button_calibrate = uibutton(app.UIFigure, 'push');
            app.button_calibrate.ButtonPushedFcn = createCallbackFcn(app, @button_calibrateButtonPushed, true);
            app.button_calibrate.Text = 'Calibrate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = calibrate_stimulus

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end